classdef SmithChart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % UI Figure
        Panel1                  matlab.ui.container.Panel          % Parâme...
        LabelSlider             matlab.ui.control.Label            % R:
        SliderR                 matlab.ui.control.Slider           % [0 10]
        PanelR                  matlab.ui.container.Panel         
        ValueR                  matlab.ui.control.NumericEditField % [0 Inf]
        Label                   matlab.ui.control.Label            % 10^
        PotR                    matlab.ui.control.NumericEditField % [-Inf ...
        Label2                  matlab.ui.control.Label            % Ω
        Label3                  matlab.ui.control.Label            % L:
        SliderL                 matlab.ui.control.Slider           % [0 10]
        PanelL                  matlab.ui.container.Panel         
        ValueL                  matlab.ui.control.NumericEditField % [0 Inf]
        Label4                  matlab.ui.control.Label            % 10^
        PotL                    matlab.ui.control.NumericEditField % [-Inf ...
        Label5                  matlab.ui.control.Label            % H
        Label6                  matlab.ui.control.Label            % C:
        SliderC                 matlab.ui.control.Slider           % [0 10]
        PanelC                  matlab.ui.container.Panel         
        ValueC                  matlab.ui.control.NumericEditField % [0 Inf]
        Label7                  matlab.ui.control.Label            % 10^
        PotC                    matlab.ui.control.NumericEditField % [-Inf ...
        Label8                  matlab.ui.control.Label            % F
        Label9                  matlab.ui.control.Label            % G:
        SliderG                 matlab.ui.control.Slider           % [0 10]
        PanelG                  matlab.ui.container.Panel         
        ValueG                  matlab.ui.control.NumericEditField % [0 Inf]
        Label10                 matlab.ui.control.Label            % 10^
        PotG                    matlab.ui.control.NumericEditField % [-Inf ...
        Label11                 matlab.ui.control.Label            % S
        ButtonCalcZ0            matlab.ui.control.Button           % Calcul...
        ButtonChange2Z0         matlab.ui.control.Button           % Entrar...
        Label20                 matlab.ui.control.Label            % Freq. ...
        Freq1                   matlab.ui.control.NumericEditField % [0 Inf]
        Label21                 matlab.ui.control.Label            % Hz
        MainPanel               matlab.ui.container.Panel         
        PanelZin                matlab.ui.container.Panel          % Imped....
        LabelNumericEditField13 matlab.ui.control.Label            % Distân...
        ValueD                  matlab.ui.control.NumericEditField % [-Inf ...
        Label15                 matlab.ui.control.Label            % λ
        ButtonCalcZin           matlab.ui.control.Button           % Calcul...
        Label16                 matlab.ui.control.Label            % Zin:
        ValueRZin               matlab.ui.control.NumericEditField % [0 Inf]
        Label17                 matlab.ui.control.Label            % + j
        ValueIZin               matlab.ui.control.NumericEditField % [-Inf ...
        Label25                 matlab.ui.control.Label            % Ω
        Panel10                 matlab.ui.container.Panel          % Casame...
        MatchingType            matlab.ui.control.DropDown         % Elemen...
        LabelNumericEditField16 matlab.ui.control.Label            % Dist. ...
        ValueDt                 matlab.ui.control.NumericEditField % [-Inf ...
        Label18                 matlab.ui.control.Label            % λ
        ButtonCalcMatching      matlab.ui.control.Button           % Realizar
        PanelZL                 matlab.ui.container.Panel          % Carga ...
        LabelNumericEditField9  matlab.ui.control.Label            % Z.L:
        ValueRZL                matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField10 matlab.ui.control.Label            % + j
        ValueIZL                matlab.ui.control.NumericEditField % [-Inf ...
        Label24                 matlab.ui.control.Label            % Ω
        ButtonPlotZL            matlab.ui.control.Button           % Plotar...
        Warning                 matlab.ui.container.Panel          % Aviso!!
        Label26                 matlab.ui.control.Label            % Imped....
        ValueRwZ0               matlab.ui.control.NumericEditField % [0 Inf]
        Label27                 matlab.ui.control.Label            % + j
        ValueIwZ0               matlab.ui.control.NumericEditField % [-Inf ...
        Label28                 matlab.ui.control.Label            % Ω
        Label29                 matlab.ui.control.Label            % Const....
        ValueRwGama             matlab.ui.control.NumericEditField % [0 Inf]
        Label30                 matlab.ui.control.Label            % + j
        ValueIwGama             matlab.ui.control.NumericEditField % [-Inf ...
        Label31                 matlab.ui.control.Label            % Ω
        Panel13                 matlab.ui.container.Panel          % Observ...
        TextArea2               matlab.ui.control.TextArea         % A Cart...
        ButtonWarningOK         matlab.ui.control.Button           % OK, Ci...
        Panel2                  matlab.ui.container.Panel          % Entrar...
        LabelNumericEditField11 matlab.ui.control.Label            % Imped....
        ValueInZ0               matlab.ui.control.NumericEditField % [-Inf ...
        Label13                 matlab.ui.control.Label            % Ω
        LabelNumericEditField12 matlab.ui.control.Label            % + j
        ValueInBeta             matlab.ui.control.NumericEditField % [-Inf ...
        Label12                 matlab.ui.control.Label            % Const....
        Label14                 matlab.ui.control.Label            % /m
        Panel8                  matlab.ui.container.Panel          % Observ...
        TextArea                matlab.ui.control.TextArea         % Lembre...
        ButtonPanel2OK          matlab.ui.control.Button           % OK
        Panel3                  matlab.ui.container.Panel          % Result...
        Label32                 matlab.ui.control.Label            % Imped....
        ValueOutZ0              matlab.ui.control.NumericEditField % [-Inf ...
        Label33                 matlab.ui.control.Label            % Ω
        Label34                 matlab.ui.control.Label            % + j
        ValueOutBeta            matlab.ui.control.NumericEditField % [-Inf ...
        Label35                 matlab.ui.control.Label            % Const....
        Label36                 matlab.ui.control.Label            % /m
        ButtonPanel3OK          matlab.ui.control.Button           % OK
        ButtonRestart           matlab.ui.control.Button           % Reiniciar
        PanelAxes               matlab.ui.container.Panel         
        UIAxes                  matlab.ui.control.UIAxes           % Smith ...
    end


    methods (Access = private)
        
        function ClearFields(app)
            % Fields of Panel1
            app.SliderR.Value = 0;
            app.ValueR.Value = 0;
            app.PotR.Value = 0;
            app.SliderL.Value = 0;
            app.ValueL.Value = 0;
            app.PotL.Value = 0;
            app.SliderC.Value = 0;
            app.ValueC.Value = 0;
            app.PotC.Value = 0;
            app.SliderG.Value = 0;
            app.ValueG.Value = 0;
            app.PotG.Value = 0;
            app.Freq1.Value = 0;
            
            % Fields of Panel2
            app.ValueInZ0.Value = 0;
            app.ValueInBeta.Value = 0;
            
            % Fields of MainPanel
            % PanelZL
            app.ValueRZL.Value = 0;
            app.ValueIZL.Value = 0;
            % PanelZin
            app.ValueD.Value = 0;
            app.ValueRZin.Value = 0;
            app.ValueIZin.Value = 0;
            % Panel10
            app.ValueDt.Value = 0;
            app.MatchingType.Value = '0';
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PanelAxes.Visible = 'off';
            app.MainPanel.Visible = 'off';
            app.Warning.Visible = 'off';
            app.Panel2.Visible = 'off';
            app.Panel3.Visible = 'off';
            axis(app.UIAxes, 'equal');
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
        end

        % SliderR value changing function
        function updateR(app, event)
            app.ValueR.Value = event.Value;
        end

        % ValueR value changed function
        function updateSliderR(app)
            app.SliderR.Value = app.ValueR.Value;
        end

        % SliderL value changing function
        function updateL(app, event)
            app.ValueL.Value = event.Value;
        end

        % ValueL value changed function
        function updateSliderL(app)
            app.SliderL.Value = app.ValueL.Value;
        end

        % SliderC value changing function
        function updateC(app, event)
            app.ValueC.Value = event.Value;
        end

        % SliderG value changing function
        function updateG(app, event)
            app.ValueG.Value = event.Value;
        end

        % ValueC value changed function
        function updateSliderC(app)
            app.SliderC.Value = app.ValueC.Value;
        end

        % ValueG value changed function
        function updateSliderG(app)
            app.SliderG.Value = app.ValueG.Value;
        end

        % ButtonChange2Z0 button pushed function
        function ChangeToZ0(app)
            app.Panel1.Visible = 'off';
            app.Panel2.Visible = 'on';
        end

        % ButtonPanel2OK button pushed function
        function Panel2OK(app)
            app.Panel2.Visible = 'off';
            app.MainPanel.Visible = 'on';
            app.PanelAxes.Visible = 'on';
            app.UIAxes.Visible = 'on';
                                              
            x = linspace(-1,1);
            y = sqrt(1-x.^(2));
            plot(app.UIAxes, x, y, 'k');
            hold(app.UIAxes, 'on');
            y = -sqrt(1-x.^(2));
            plot(app.UIAxes, x, y, 'k');
            hold(app.UIAxes, 'off');
            
            app.ValueOutZ0.Value = app.ValueInZ0.Value;
            app.ValueOutBeta.Value = app.ValueInBeta.Value;
        end

        % ButtonCalcZ0 button pushed function
        function CalcZ0(app)
            app.Panel1.Visible = 'off';
            
            R = app.ValueR.Value*10^(app.PotR.Value);
            L = app.ValueL.Value*10^(app.PotL.Value);
            C = app.ValueC.Value*10^(app.PotC.Value);
            G = app.ValueG.Value*10^(app.PotG.Value);
            f = app.Freq1.Value;
            
            Z0 = sqrt((R+j*2*pi*L)/(G+j*2*pi*C));
            gama = sqrt((R+j*2*pi*L)*(G+j*2*pi*C));
            
            if((imag(Z0) == 0) && (real(gama) == 0))
                app.Panel3.Visible = 'on';
                app.ValueOutZ0.Value = real(Z0);
                app.ValueOutBeta.Value = imag(gama);
            else
                app.Warning.Visible = 'on';
                app.ValueRwZ0.Value = real(Z0);
                app.ValueIwZ0.Value = imag(Z0);
                app.ValueRwGama.Value = real(gama);
                app.ValueIwGama.Value = imag(gama);
            end
        end

        % ButtonWarningOK button pushed function
        function WarningOK(app)
            app.Panel1.Visible = 'on';
            app.Warning.Visible = 'off';
        end

        % ButtonPanel3OK button pushed function
        function Panel3OK(app)
            app.Panel3.Visible = 'off';
            app.MainPanel.Visible = 'on';
            app.PanelAxes.Visible = 'on';
            app.UIAxes.Visible = 'on';
            
            x = linspace(-1,1);
            y = sqrt(1-x.^(2));
            plot(app.UIAxes, x, y, 'k');
            hold(app.UIAxes, 'on');
            y = -sqrt(1-x.^(2));
            plot(app.UIAxes, x, y, 'k');
            hold(app.UIAxes, 'off');
        end

        % ButtonRestart button pushed function
        function Restart(app)
            app.PanelAxes.Visible = 'off';
            app.MainPanel.Visible = 'off';
            app.Warning.Visible = 'off';
            app.Panel2.Visible = 'off';
            app.Panel3.Visible = 'off';
            app.Panel1.Visible = 'on';
            ClearFields(app);
        end

        % ButtonPlotZL button pushed function
        function PlotZL(app)
            zl = (app.ValueRZL.Value + j*app.ValueIZL.Value)/app.ValueOutZ0.Value;
            
            hold(app.UIAxes, 'on');
            
            r = real(zl);
            deltaX1 = -1/(1+r) + (r/(1+r));
            deltaX2 = 1/(1+r) + (r/(1+r));
            x = linspace(deltaX1,deltaX2);
            y = sqrt((1/(1+r))^2-(x-(r/(1+r))).^(2));
            plot(app.UIAxes, x, y, 'r');
            y = -sqrt((1/(1+r))^2-(x-(r/(1+r))).^(2));
            plot(app.UIAxes, x, y, 'r');
            
            im = imag(zl);
            deltaX1 = -1;
            deltaX2 = 1;
            x = linspace(deltaX1,deltaX2);
            y = -sqrt((1/im)^2-(x-1).^(2)) + 1/im;
            plot(app.UIAxes, x, y, 'b');
            
            hold(app.UIAxes, 'off');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Panel1 = uipanel(app.UIFigure);
            app.Panel1.Title = 'Parâmetros Distribuídos';
            app.Panel1.Units = 'pixels';
            app.LabelSlider = uilabel(app.Panel1);
            app.LabelSlider.Text = 'R:';
            app.SliderR = uislider(app.Panel1);
            app.SliderR.Limits = [0 10];
            app.SliderR.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.SliderR.ValueChangingFcn = createCallbackFcn(app, @updateR, true);
            app.PanelR = uipanel(app.Panel1);
            app.PanelR.Units = 'pixels';
            app.ValueR = uieditfield(app.PanelR, 'numeric');
            app.ValueR.ValueChangedFcn = createCallbackFcn(app, @updateSliderR);
            app.ValueR.Limits = [0 Inf];
            app.ValueR.ValueDisplayFormat = '%.3f';
            app.Label = uilabel(app.PanelR);
            app.Label.Text = '10^';
            app.PotR = uieditfield(app.PanelR, 'numeric');
            app.PotR.ValueDisplayFormat = '%.0f';
            app.Label2 = uilabel(app.PanelR);
            app.Label2.Text = 'Ω';
            app.Label3 = uilabel(app.Panel1);
            app.Label3.Text = 'L:';
            app.SliderL = uislider(app.Panel1);
            app.SliderL.Limits = [0 10];
            app.SliderL.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.SliderL.ValueChangingFcn = createCallbackFcn(app, @updateL, true);
            app.PanelL = uipanel(app.Panel1);
            app.PanelL.Units = 'pixels';
            app.ValueL = uieditfield(app.PanelL, 'numeric');
            app.ValueL.ValueChangedFcn = createCallbackFcn(app, @updateSliderL);
            app.ValueL.Limits = [0 Inf];
            app.ValueL.ValueDisplayFormat = '%.3f';
            app.Label4 = uilabel(app.PanelL);
            app.Label4.Text = '10^';
            app.PotL = uieditfield(app.PanelL, 'numeric');
            app.PotL.ValueDisplayFormat = '%.0f';
            app.Label5 = uilabel(app.PanelL);
            app.Label5.Text = 'H';
            app.Label6 = uilabel(app.Panel1);
            app.Label6.Text = 'C:';
            app.SliderC = uislider(app.Panel1);
            app.SliderC.Limits = [0 10];
            app.SliderC.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.SliderC.ValueChangingFcn = createCallbackFcn(app, @updateC, true);
            app.PanelC = uipanel(app.Panel1);
            app.PanelC.Units = 'pixels';
            app.ValueC = uieditfield(app.PanelC, 'numeric');
            app.ValueC.ValueChangedFcn = createCallbackFcn(app, @updateSliderC);
            app.ValueC.Limits = [0 Inf];
            app.ValueC.ValueDisplayFormat = '%.3f';
            app.Label7 = uilabel(app.PanelC);
            app.Label7.Text = '10^';
            app.PotC = uieditfield(app.PanelC, 'numeric');
            app.PotC.ValueDisplayFormat = '%.0f';
            app.Label8 = uilabel(app.PanelC);
            app.Label8.Text = 'F';
            app.Label9 = uilabel(app.Panel1);
            app.Label9.Text = 'G:';
            app.SliderG = uislider(app.Panel1);
            app.SliderG.Limits = [0 10];
            app.SliderG.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.SliderG.ValueChangingFcn = createCallbackFcn(app, @updateG, true);
            app.PanelG = uipanel(app.Panel1);
            app.PanelG.Units = 'pixels';
            app.ValueG = uieditfield(app.PanelG, 'numeric');
            app.ValueG.ValueChangedFcn = createCallbackFcn(app, @updateSliderG);
            app.ValueG.Limits = [0 Inf];
            app.ValueG.ValueDisplayFormat = '%.3f';
            app.Label10 = uilabel(app.PanelG);
            app.Label10.Text = '10^';
            app.PotG = uieditfield(app.PanelG, 'numeric');
            app.PotG.ValueDisplayFormat = '%.0f';
            app.Label11 = uilabel(app.PanelG);
            app.Label11.Text = 'S';
            app.ButtonCalcZ0 = uibutton(app.Panel1, 'push');
            app.ButtonCalcZ0.ButtonPushedFcn = createCallbackFcn(app, @CalcZ0);
            app.ButtonCalcZ0.Text = 'Calcular Z0 e γ';
            app.ButtonChange2Z0 = uibutton(app.Panel1, 'push');
            app.ButtonChange2Z0.ButtonPushedFcn = createCallbackFcn(app, @ChangeToZ0);
            app.ButtonChange2Z0.Text = 'Entrar com Z0 e γ';
            app.Label20 = uilabel(app.Panel1);
            app.Label20.Text = 'Freq. de Operação (f):';
            app.Freq1 = uieditfield(app.Panel1, 'numeric');
            app.Freq1.Limits = [0 Inf];
            app.Freq1.ValueDisplayFormat = '%e';
            app.Label21 = uilabel(app.Panel1);
            app.Label21.Text = 'Hz';
            app.MainPanel = uipanel(app.UIFigure);
            app.MainPanel.Units = 'pixels';
            app.PanelZin = uipanel(app.MainPanel);
            app.PanelZin.Title = 'Imped. de Entrada (Zin)';
            app.PanelZin.Units = 'pixels';
            app.LabelNumericEditField13 = uilabel(app.PanelZin);
            app.LabelNumericEditField13.Text = 'Distância da Carga:';
            app.ValueD = uieditfield(app.PanelZin, 'numeric');
            app.Label15 = uilabel(app.PanelZin);
            app.Label15.Text = 'λ';
            app.ButtonCalcZin = uibutton(app.PanelZin, 'push');
            app.ButtonCalcZin.Text = 'Calcular Zin';
            app.Label16 = uilabel(app.PanelZin);
            app.Label16.Text = 'Zin:';
            app.ValueRZin = uieditfield(app.PanelZin, 'numeric');
            app.ValueRZin.Limits = [0 Inf];
            app.ValueRZin.ValueDisplayFormat = '%.3f';
            app.ValueRZin.Editable = 'off';
            app.Label17 = uilabel(app.PanelZin);
            app.Label17.Text = '+ j';
            app.ValueIZin = uieditfield(app.PanelZin, 'numeric');
            app.ValueIZin.ValueDisplayFormat = '%.3f';
            app.ValueIZin.Editable = 'off';
            app.Label25 = uilabel(app.PanelZin);
            app.Label25.Text = 'Ω';
            app.Panel10 = uipanel(app.MainPanel);
            app.Panel10.Title = 'Casamento de Impedância';
            app.Panel10.Units = 'pixels';
            app.MatchingType = uidropdown(app.Panel10);
            app.MatchingType.Items = {'Elemento Reativo - L (Série)', 'Elemento Reativo - L (Paralelo)', 'Elemento Reativo - C (Série)', 'Elemento Reativo - C (Paralelo)', 'Transformador de1/4 de Onda', 'Toco Simples', 'Toco Duplo'};
            app.MatchingType.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.MatchingType.Value = '0';
            app.LabelNumericEditField16 = uilabel(app.Panel10);
            app.LabelNumericEditField16.Text = 'Dist. entre Tocos (d):';
            app.ValueDt = uieditfield(app.Panel10, 'numeric');
            app.Label18 = uilabel(app.Panel10);
            app.Label18.Text = 'λ';
            app.ButtonCalcMatching = uibutton(app.Panel10, 'push');
            app.ButtonCalcMatching.Text = 'Realizar';
            app.PanelZL = uipanel(app.MainPanel);
            app.PanelZL.Title = 'Carga na Linha de Transmissão';
            app.PanelZL.Units = 'pixels';
            app.LabelNumericEditField9 = uilabel(app.PanelZL);
            app.LabelNumericEditField9.Text = 'Z.L:';
            app.ValueRZL = uieditfield(app.PanelZL, 'numeric');
            app.ValueRZL.Limits = [0 Inf];
            app.ValueRZL.ValueDisplayFormat = '%.3f';
            app.LabelNumericEditField10 = uilabel(app.PanelZL);
            app.LabelNumericEditField10.Text = '+ j';
            app.ValueIZL = uieditfield(app.PanelZL, 'numeric');
            app.ValueIZL.ValueDisplayFormat = '%.3f';
            app.Label24 = uilabel(app.PanelZL);
            app.Label24.Text = 'Ω';
            app.ButtonPlotZL = uibutton(app.PanelZL, 'push');
            app.ButtonPlotZL.ButtonPushedFcn = createCallbackFcn(app, @PlotZL);
            app.ButtonPlotZL.Text = 'Plotar Z.L';
            app.Warning = uipanel(app.UIFigure);
            app.Warning.Title = 'Aviso!!';
            app.Warning.Units = 'pixels';
            app.Label26 = uilabel(app.Warning);
            app.Label26.Text = 'Imped. Característica (Z0):';
            app.ValueRwZ0 = uieditfield(app.Warning, 'numeric');
            app.ValueRwZ0.Limits = [0 Inf];
            app.ValueRwZ0.ValueDisplayFormat = '%.3f';
            app.ValueRwZ0.Editable = 'off';
            app.Label27 = uilabel(app.Warning);
            app.Label27.Text = '+ j';
            app.ValueIwZ0 = uieditfield(app.Warning, 'numeric');
            app.ValueIwZ0.ValueDisplayFormat = '%.3f';
            app.ValueIwZ0.Editable = 'off';
            app.Label28 = uilabel(app.Warning);
            app.Label28.Text = 'Ω';
            app.Label29 = uilabel(app.Warning);
            app.Label29.Text = 'Const. de Propagação (γ):';
            app.ValueRwGama = uieditfield(app.Warning, 'numeric');
            app.ValueRwGama.Limits = [0 Inf];
            app.ValueRwGama.ValueDisplayFormat = '%.3f';
            app.ValueRwGama.Editable = 'off';
            app.Label30 = uilabel(app.Warning);
            app.Label30.Text = '+ j';
            app.ValueIwGama = uieditfield(app.Warning, 'numeric');
            app.ValueIwGama.ValueDisplayFormat = '%.3f';
            app.ValueIwGama.Editable = 'off';
            app.Label31 = uilabel(app.Warning);
            app.Label31.Text = 'Ω';
            app.Panel13 = uipanel(app.Warning);
            app.Panel13.Title = 'Observação';
            app.Panel13.Units = 'pixels';
            app.TextArea2 = uitextarea(app.Panel13);
            app.TextArea2.Editable = 'off';
            app.TextArea2.Value = {'A Carta de Smith só pode ser utilizada para Linhas sem Perda, isto é, Imped. Característica real e Const. de Propagação puramente imaginária. Por este motivo, com estes parâmetros, não é possível utilizá-la.'};
            app.ButtonWarningOK = uibutton(app.Warning, 'push');
            app.ButtonWarningOK.ButtonPushedFcn = createCallbackFcn(app, @WarningOK);
            app.ButtonWarningOK.Text = 'OK, Ciente';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Entrar com Z0 e γ';
            app.Panel2.Units = 'pixels';
            app.LabelNumericEditField11 = uilabel(app.Panel2);
            app.LabelNumericEditField11.Text = 'Imped. Característica (Z0):';
            app.ValueInZ0 = uieditfield(app.Panel2, 'numeric');
            app.Label13 = uilabel(app.Panel2);
            app.Label13.Text = 'Ω';
            app.LabelNumericEditField12 = uilabel(app.Panel2);
            app.LabelNumericEditField12.Text = '+ j';
            app.ValueInBeta = uieditfield(app.Panel2, 'numeric');
            app.Label12 = uilabel(app.Panel2);
            app.Label12.Text = 'Const. de Propagação (γ):';
            app.Label14 = uilabel(app.Panel2);
            app.Label14.Text = '/m';
            app.Panel8 = uipanel(app.Panel2);
            app.Panel8.Title = 'Observação';
            app.Panel8.Units = 'pixels';
            app.TextArea = uitextarea(app.Panel8);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Lembre-se: Para utilização da Carta de Smith, a Imped. Característica deve ser real e a Const. de Propagação deve ser puramente imaginária.'};
            app.ButtonPanel2OK = uibutton(app.Panel2, 'push');
            app.ButtonPanel2OK.ButtonPushedFcn = createCallbackFcn(app, @Panel2OK);
            app.ButtonPanel2OK.Text = 'OK';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Resultado de Z0 e γ';
            app.Panel3.Units = 'pixels';
            app.Label32 = uilabel(app.Panel3);
            app.Label32.Text = 'Imped. Característica (Z0):';
            app.ValueOutZ0 = uieditfield(app.Panel3, 'numeric');
            app.ValueOutZ0.Editable = 'off';
            app.Label33 = uilabel(app.Panel3);
            app.Label33.Text = 'Ω';
            app.Label34 = uilabel(app.Panel3);
            app.Label34.Text = '+ j';
            app.ValueOutBeta = uieditfield(app.Panel3, 'numeric');
            app.ValueOutBeta.Editable = 'off';
            app.Label35 = uilabel(app.Panel3);
            app.Label35.Text = 'Const. de Propagação (γ):';
            app.Label36 = uilabel(app.Panel3);
            app.Label36.Text = '/m';
            app.ButtonPanel3OK = uibutton(app.Panel3, 'push');
            app.ButtonPanel3OK.ButtonPushedFcn = createCallbackFcn(app, @Panel3OK);
            app.ButtonPanel3OK.Text = 'OK';
            app.ButtonRestart = uibutton(app.UIFigure, 'push');
            app.ButtonRestart.ButtonPushedFcn = createCallbackFcn(app, @Restart);
            app.ButtonRestart.Icon = 'RestartIcon.png';
            app.ButtonRestart.Text = 'Reiniciar';
            app.PanelAxes = uipanel(app.UIFigure);
            app.PanelAxes.Units = 'pixels';
            app.UIAxes = uiaxes(app.PanelAxes);
            title(app.UIAxes, 'Smith Chart');
        end
    end

    methods (Access = public)

        % Construct app
        function app = SmithChart()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end